classdef Holography_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        x106Label                     matlab.ui.control.Label
        nmLabel                       matlab.ui.control.Label
        SparsitySlider                matlab.ui.control.Slider
        SparsitySliderLabel           matlab.ui.control.Label
        WavelengthSlider              matlab.ui.control.Slider
        WavelengthSliderLabel         matlab.ui.control.Label
        RunHolographyAlgorithmButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("matlab_ihrri_toolbox");
            evalin("base", "reconstruct_camera_feed");
        end

        % Button pushed function: RunHolographyAlgorithmButton
        function RunHolographyAlgorithmButtonPushed(app, event)
            new_params.lambda = app.WavelengthSlider.Value * 1e-9;
            new_params.muSparse = app.SparsitySlider.Value * 1e-6;
            assignin("base", "new_params", new_params);
            evalin("base", "update_parameters_callback")
            evalin("base", "reconstruction_script");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunHolographyAlgorithmButton = uibutton(app.UIFigure, 'push');
            app.RunHolographyAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunHolographyAlgorithmButtonPushed, true);
            app.RunHolographyAlgorithmButton.Text = 'Run Holography Algorithm!!!';
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.Limits = [400 700];
            app.WavelengthSlider.Value = 400;
            app.SparsitySliderLabel = uilabel(app.UIFigure);
            app.SparsitySliderLabel.Text = 'Sparsity';
            app.SparsitySlider = uislider(app.UIFigure);
            app.SparsitySlider.Limits = [1 101];
            app.SparsitySlider.Value = 1;
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            app.x106Label = uilabel(app.UIFigure);
            app.x106Label.Text = 'x10^-6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Holography_UI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end